classdef fifth_update < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BrowseSong        matlab.ui.control.Image
        PlayButton        matlab.ui.control.Image
        ForwardButton     matlab.ui.control.Image
        ReverseButton     matlab.ui.control.Image
        SongProgress      matlab.ui.control.Slider
        SongTitle         matlab.ui.control.Label
        LoopSongs         matlab.ui.control.Image
        ShuffleSongs      matlab.ui.control.Image
        instructionLabel  matlab.ui.control.Label
        pause             matlab.ui.control.Image
        VolSliderLabel    matlab.ui.control.Label
        Volume            matlab.ui.control.Slider
        PitchSliderLabel  matlab.ui.control.Label
        Pitch             matlab.ui.control.Slider
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: BrowseSong
        function BrowseSongImageClicked(app, event)
            files=uigetfile('*.mp3','Select the songs.','MultiSelect','on');
            if(length(files)==0)
                app.instructionLabel.Text="No Songs Selected! Kindly try again.";
                app.instructionLabel.FontColor=[1,0,0];
            else
                app.instructionLabel.Text="Songs have been loaded.";
                app.instructionLabel.FontColor=[0,1,0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseSong = uiimage(app.UIFigure);
            app.BrowseSong.ImageClickedFcn = createCallbackFcn(app, @BrowseSongImageClicked, true);
            app.BrowseSong.ImageSource = 'music_player.png';
            app.PlayButton = uiimage(app.UIFigure);
            app.PlayButton.ImageSource = 'Play1.png';
            app.ForwardButton = uiimage(app.UIFigure);
            app.ForwardButton.ImageSource = 'forward.png';
            app.ReverseButton = uiimage(app.UIFigure);
            app.ReverseButton.ImageSource = 'backward.png';
            app.SongProgress = uislider(app.UIFigure);
            app.SongProgress.Limits = [1 100];
            app.SongProgress.MajorTicks = [];
            app.SongProgress.MinorTicks = [];
            app.SongProgress.Value = 1;
            app.SongTitle = uilabel(app.UIFigure);
            app.SongTitle.Text = 'Demo';
            app.LoopSongs = uiimage(app.UIFigure);
            app.LoopSongs.ImageSource = 'repeat.png';
            app.ShuffleSongs = uiimage(app.UIFigure);
            app.ShuffleSongs.ImageSource = 'shuffle.png';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '';
            app.pause = uiimage(app.UIFigure);
            app.pause.ImageSource = 'pause.png';
            app.VolSliderLabel = uilabel(app.UIFigure);
            app.VolSliderLabel.Text = 'Vol';
            app.Volume = uislider(app.UIFigure);
            app.Volume.MajorTicks = [];
            app.Volume.MinorTicks = [];
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.Pitch = uislider(app.UIFigure);
            app.Pitch.Limits = [1 10];
            app.Pitch.MajorTicks = [];
            app.Pitch.MinorTicks = [];
            app.Pitch.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fifth_update

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end