classdef final_code < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BrowseSong        matlab.ui.control.Image
        PlayButton        matlab.ui.control.Image
        ForwardButton     matlab.ui.control.Image
        ReverseButton     matlab.ui.control.Image
        SongTitle         matlab.ui.control.Label
        LoopSongs         matlab.ui.control.Image
        ShuffleSongs      matlab.ui.control.Image
        instructionLabel  matlab.ui.control.Label
        pause             matlab.ui.control.Image
        VolSliderLabel    matlab.ui.control.Label
        Volume            matlab.ui.control.Slider
        PitchSliderLabel  matlab.ui.control.Label
        Pitch             matlab.ui.control.Slider
    end

    
    methods (Access = private)
        
        function AssignSongsinSequence(app,event,firsttime)
            global files;global songNumber;songNumber=1;global songselect;
            if(firsttime==1)
                if(class(files)=="char")
                    app.instructionLabel.Text="1 Song Loaded";
                    app.instructionLabel.FontColor=[1,1,0];
                    songselect=0;
                    pause(3);
                else
                    app.instructionLabel.Text=string(length(files))+" Songs Loaded";
                    app.instructionLabel.FontColor=[1,1,0];
                    songselect=1;
                    pause(3);
                end
            else
                songselect=1;
            end
        end
        function playSong(app,event,cSr)
        global curr1;global total1;global player;%global cSr;
        play(player,cSr);           
        end
        function checkNow(app,event)
        global files;
        global checkstill;global sN;
        for i=1:length(files)
            checkstill=1;
            sN=sN+1;
            PlayButtonImageClicked(app,event);
        end
        
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: BrowseSong
        function BrowseSongImageClicked(app, event)
            global files;global pausing;global checkstill;global pitchValue;global VolumeValue;
            pitchValue=0;VolumeValue=1;global pitchCheck;global VolumeCheck;pitchCheck=0;VolumeCheck=0;
            checkstill=1;global sN;sN=1;global sanitationchecks;
            sanitationchecks=0;
            pausing=0;global countplayers;countplayers=1;
            firsttime=1;
            files=uigetfile('*.mp3','Select the songs.','MultiSelect','on');
            %disp(length(files));
            if(length(files)<=1)
                app.instructionLabel.Text="No Songs Selected! Kindly try again.";
                app.instructionLabel.FontColor=[1,0,0];
                pause(3);
            else
                app.instructionLabel.Text="Songs have been loaded.";
                app.instructionLabel.FontColor=[0,1,0];
                pause(3);
                AssignSongsinSequence(app,event,firsttime);
                
            end
        end

        % Image clicked function: PlayButton
        function PlayButtonImageClicked(app, event)
            global files;global songNumber;global songselect;global pausing;global player;
            global pitchValue;global pitchValue;global VolumeValue;global pitchStart;
            global sN;global checkstill;global pitchCheck;global pausingCC;global countplayers;
            global VolumeCheck;global sanitationchecks;
            if(pitchCheck==1&&VolumeCheck==0)
               pitchCheck=0;
               %disp("files are:- "+string(files));
               if(class(files)=="char")
                [y,Fs]=audioread(files);
                else
                 [y,Fs]=audioread(string(files(sN)));   
               end
               if(countplayers~=1)
                   clear player;countplayers=1;
               end
              player=audioplayer(y*VolumeValue,Fs+pitchValue);
              disp("Value of songselect: "+string(songselect));
              app.SongTitle.Text=string(string(files(sN)));
              app.SongTitle.FontWeight='bold';
              app.SongTitle.FontColor=[0,0,0];
              playblocking(player,pitchStart);
              checkNow(app,event);
            elseif(pitchCheck==0&&VolumeCheck==1)
                VolumeCheck=0;
                if(class(files)=="char")
                [y,Fs]=audioread(files);
                else
                 [y,Fs]=audioread(string(files(sN)));   
                end
                if(countplayers~=1)
                   clear player;countplayers=1;
               end
              player=audioplayer(y*VolumeValue,Fs+pitchValue);
              disp("Value of songselect: "+string(songselect));
              app.SongTitle.Text=string(files(sN));
              app.SongTitle.FontWeight='bold';
              app.SongTitle.FontColor=[0,0,0];
              playblocking(player,pitchStart);
              checkNow(app,event);
            elseif(pitchCheck==1&&VolumeCheck==1)
                pitchCheck=0;VolumeCheck=0;
                if(class(files)=="char")
                [y,Fs]=audioread(files);
                else
                 [y,Fs]=audioread(string(files(sN)));   
                end
                if(countplayers~=1)
                   clear player;countplayers=1;
               end
              player=audioplayer(y*VolumeValue,Fs+pitchValue);
              disp("Value of songselect: "+string(songselect));
              app.SongTitle.Text=string(files(sN));
              app.SongTitle.FontWeight='bold';
              app.SongTitle.FontColor=[0,0,0];
              playblocking(player,pitchStart);
              checkNow(app,event);
            else
            if(pausing==1)
                disp("pass");pausing=0;clear player;
                if(class(files)=="char")
                [y,Fs]=audioread(files);
                else
                 [y,Fs]=audioread(string(files(sN)));   
                end
                if(countplayers~=1)
                    countplayers=1;
                   clear player;
               end
              player=audioplayer(y*VolumeValue,Fs+pitchValue);
              disp("Value of songselect: "+string(songselect));
              app.SongTitle.Text=string(files(sN));
              app.SongTitle.FontWeight='bold';
              app.SongTitle.FontColor=[0,0,0];
              playblocking(player,pausingCC);
              checkNow(app,event);
            else
            if(songselect==0)
            app.ReverseButton.Enable=false;
            app.ForwardButton.Enable=false;
            app.LoopSongs.Enable=false;
            app.ShuffleSongs.Enable=false;
            [y,Fs]=audioread(files);
            player=audioplayer(y,Fs);
            app.SongTitle.Text=files;
            app.SongTitle.FontWeight='bold';
            app.SongTitle.FontColor=[0,0,0];
            timeduration=length(y)/Fs;
           for i=1:timeduration
               play(player);
               pause(1);
           end
            disp('song 1 play done');
            else
                app.ReverseButton.Enable=true;
                app.ForwardButton.Enable=true;
                app.LoopSongs.Enable=true;
                app.ShuffleSongs.Enable=true;
                if(checkstill==1)
                    if(sN>length(files))   
                    app.instructionLabel.Text="Media over";
                    app.instructionLabel.FontColor=[0,1,0];
                    else
                 [y,Fs]=audioread(string(files(sN)));
              player=audioplayer(y*VolumeValue,Fs+pitchValue);
              disp("Value of songselect: "+string(songselect));
              app.SongTitle.Text=string(files(sN));
              app.SongTitle.FontWeight='bold';
              app.SongTitle.FontColor=[0,0,0];
              playblocking(player);
              if(sanitationchecks==0)
                 checkNow(app,event); 
              end
              
                end
                else
                    app.instructionLabel.Text="Media over";
                    app.instructionLabel.FontColor=[0,1,0];
                    app.ReverseButton.Enable=false;
                    app.ForwardButton.Enable=false;
                    app.LoopSongs.Enable=false;
                    app.ShuffleSongs.Enable=false;
                end
            end   
            end
            end
        end

        % Image clicked function: pause
        function pauseImageClicked(app, event)
            global songselect;global pausing;global player;
            pausing=1;global countplayers;countplayers=countplayers+1;global sanitationchecks;
            sanitationchecks=1;
            global pausingCC;
            disp("Happening");
                pausingCC=get(player,'CurrentSample');
                pause(player);
                clear player;
                waitfor(app.PlayButton);

        end

        % Image clicked function: ForwardButton
        function ForwardButtonImageClicked(app, event)
            global player;
            global files;global sanitationchecks;
            sanitationchecks=1;
            stop(player);
            global countplayers;countplayers=countplayers+1;
            global sN;
            sN=sN+1;
            if(sN==length(files))
                app.ForwardButton.Enable=false;
                sN=sN-1;
                app.instructionLabel.Text="Media over";
                app.instructionLabel.FontColor=[0,1,0];
                app.ReverseButton.Enable=false;
                app.ForwardButton.Enable=false;
                app.LoopSongs.Enable=false;
                app.ShuffleSongs.Enable=false;
                app.PlayButton.Enable=false;
                app.pause.Enable=false;
                clear all;
            else
                clear player;
                PlayButtonImageClicked(app,event);
            end
        end

        % Image clicked function: ReverseButton
        function ReverseButtonImageClicked(app, event)
            global player;
            stop(player);
            global sN;
            global countplayers;countplayers=countplayers+1;
            sN=sN-1;
            if(sN==0)
                app.instructionLabel.Text="Media over";
                app.instructionLabel.FontColor=[0,1,0];
                app.ReverseButton.Enable=false;
                app.ForwardButton.Enable=false;
                app.LoopSongs.Enable=false;
                app.ShuffleSongs.Enable=false;
                app.PlayButton.Enable=false;
                app.pause.Enable=false;
                clear all;
            else
                clear player;
                PlayButtonImageClicked(app,event);
            end
        end

        % Value changed function: Pitch
        function PitchValueChanged(app, event)
            global pitchValue;  
            global pitchCheck;
            global pitchStart;
            global countplayers;countplayers=countplayers+1;
            global player;
            pitchStart=get(player,"CurrentSample");
            pitchCheck=1;
            pitchValue = app.Pitch.Value;
            PlayButtonImageClicked(app,event);
        end

        % Value changed function: Volume
        function VolumeValueChanged(app, event)
            global VolumeValue;  
            global VolumeCheck;
            global pitchStart;
            global countplayers;countplayers=countplayers+1;
            global player;
            pitchStart=get(player,"CurrentSample");
            VolumeCheck=1;
            VolumeValue = app.Pitch.Value;
            PlayButtonImageClicked(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseSong = uiimage(app.UIFigure);
            app.BrowseSong.ImageClickedFcn = createCallbackFcn(app, @BrowseSongImageClicked, true);
            app.BrowseSong.ImageSource = 'music_player.png';
            app.PlayButton = uiimage(app.UIFigure);
            app.PlayButton.ImageClickedFcn = createCallbackFcn(app, @PlayButtonImageClicked, true);
            app.PlayButton.ImageSource = 'Play1.png';
            app.ForwardButton = uiimage(app.UIFigure);
            app.ForwardButton.ImageClickedFcn = createCallbackFcn(app, @ForwardButtonImageClicked, true);
            app.ForwardButton.ImageSource = 'forward.png';
            app.ReverseButton = uiimage(app.UIFigure);
            app.ReverseButton.ImageClickedFcn = createCallbackFcn(app, @ReverseButtonImageClicked, true);
            app.ReverseButton.ImageSource = 'backward.png';
            app.SongTitle = uilabel(app.UIFigure);
            app.SongTitle.Text = '';
            app.LoopSongs = uiimage(app.UIFigure);
            app.LoopSongs.ImageSource = 'repeat.png';
            app.ShuffleSongs = uiimage(app.UIFigure);
            app.ShuffleSongs.ImageSource = 'shuffle.png';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '';
            app.pause = uiimage(app.UIFigure);
            app.pause.ImageClickedFcn = createCallbackFcn(app, @pauseImageClicked, true);
            app.pause.ImageSource = 'pause.png';
            app.VolSliderLabel = uilabel(app.UIFigure);
            app.VolSliderLabel.Text = 'Vol';
            app.Volume = uislider(app.UIFigure);
            app.Volume.MajorTicks = [];
            app.Volume.ValueChangedFcn = createCallbackFcn(app, @VolumeValueChanged, true);
            app.Volume.MinorTicks = [];
            app.Volume.Value = 50;
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.Pitch = uislider(app.UIFigure);
            app.Pitch.Limits = [1 10];
            app.Pitch.MajorTicks = [];
            app.Pitch.ValueChangedFcn = createCallbackFcn(app, @PitchValueChanged, true);
            app.Pitch.MinorTicks = [];
            app.Pitch.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_code

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end