classdef first_update < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BrowseSong        matlab.ui.control.Image
        PlayButton        matlab.ui.control.Image
        ForwardButton     matlab.ui.control.Image
        ReverseButton     matlab.ui.control.Image
        SongTitle         matlab.ui.control.Label
        instructionLabel  matlab.ui.control.Label
        pause             matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function AssignSongsinSequence(app,event,firsttime)
        global files;global sN;global onefile;global iC;
        if(firsttime==1)
        if(sN>length(files))
            app.instructionLabel.Text="Media over";
            app.instructionLabel.FontColor=[0,1,0];
        else
            if(class(files)=="char")
                onefile=1;
                app.instructionLabel.Text="1 Song selected";
                pause(3);
                sN=1;
            else
                onefile=0;
               app.instructionLabel.Text=string(length(files))+ " Songs selected";
               app.instructionLabel.FontColor=[0,1,0];
               sN=1;
               pause(3);
               iC=1;
            end
        end
        else
            if(sN+1>length(files))
                app.instructionLabel.Text="Media over";
                app.instructionLabel.FontColor=[0,1,0];
                clear all;
            else
                sN=sN+1;
                PlayButtonImageClicked(app,event);
            end
        end   
        end
    end


    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseSong = uiimage(app.UIFigure);
            app.BrowseSong.ImageSource = 'music_player.png';
            app.PlayButton = uiimage(app.UIFigure);
            app.PlayButton.ImageSource = 'Play1.png';
            app.ForwardButton = uiimage(app.UIFigure);
            app.ForwardButton.ImageSource = 'forward.png';
            app.ReverseButton = uiimage(app.UIFigure);
            app.ReverseButton.ImageSource = 'backward.png';
            app.SongTitle = uilabel(app.UIFigure);
            app.SongTitle.Text = '';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '';
            app.pause = uiimage(app.UIFigure);
            app.pause.ImageSource = 'pause.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = first_update

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end