classdef musicPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BrowseSong        matlab.ui.control.Image
        PlayButton        matlab.ui.control.Image
        ForwardButton     matlab.ui.control.Image
        ReverseButton     matlab.ui.control.Image
        SongTitle         matlab.ui.control.Label
        instructionLabel  matlab.ui.control.Label
        pause             matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function AssignSongsinSequence(app,event,firsttime)
        global files;global sN;global onefile;global iC;
        if(firsttime==1)
        if(sN>length(files))
            app.instructionLabel.Text="Media over";
            app.instructionLabel.FontColor=[0,1,0];
        else
            if(class(files)=="char")
                onefile=1;
                app.instructionLabel.Text="1 Song selected";
                pause(3);
                sN=1;
            else
                onefile=0;
               app.instructionLabel.Text=string(length(files))+ " Songs selected";
               app.instructionLabel.FontColor=[0,1,0];
               sN=1;
               pause(3);
               iC=1;
            end
        end
        else
            if(sN+1>length(files))
                app.instructionLabel.Text="Media over";
                app.instructionLabel.FontColor=[0,1,0];
                clear all;
            else
                sN=sN+1;
                PlayButtonImageClicked(app,event);
            end
        end   
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: BrowseSong
        function BrowseSongImageClicked(app, event)
            global files;global sN;sN=1;global pitchenter;pitchcheck=0;global volumecheck;columecheck=0;
            global sRate;sRate=1;global statusforward;global statusback;global shuffle;global repeat;
            global iC;iC=0;
            statusback=0;statusforward=0;shuffle=0;repeat=0;
            files=uigetfile('*.mp3','Select the songs.','MultiSelect','on');
            %disp(length(files));
            if(length(files)<=1)
                app.instructionLabel.Text="No Songs Selected! Kindly try again.";
                app.instructionLabel.FontColor=[1,0,0];
                pause(3);
            else
                app.instructionLabel.Text="Songs have been loaded.";
                app.instructionLabel.FontColor=[0,1,0];
                pause(3);
                AssignSongsinSequence(app,event,1);
                
            end
        end

        % Image clicked function: PlayButton
        function PlayButtonImageClicked(app, event)
            global files;global player;global sN;
            global onefile;global statusPause;global iC;
            name="";global cRR;
            global statusforward;global statusback;global shuffle;global repeat;
            if(iC~=1)
                app.instructionLabel.Text="Please wait while the songs are loading!";
                app.instructionLabel.FontColor=[1,0,0];
                stop(player);
                clear all;
                clc;
                return;
            end
            if(sN==length(files))
                app.instructionLabel.Text="All Songs completed!";
                return;
            else
            if(onefile==1)
                
                app.PlayButton.Enable="off";
                app.ReverseButton.Enable="off";
                app.ForwardButton.Enable="off";
                app.ReverseButton.Enable="off";
                [y,fs]=audioread(files);
                name=files;
            else
                app.PlayButton.Enable="on";
                app.ReverseButton.Enable="on";
                app.ForwardButton.Enable="on";
                app.ReverseButton.Enable="on";
                if(sN>length(files))
                    app.instructionLabel.Text="Media Over";
                    app.instructionLabel.FontColor=[0,1,0];
                    return;
                end
                [y,fs]=audioread(string(files(sN)));
                name=string(files(sN));
            end
            if(statusPause==1)
               clear player;
               statusPause=0;
               app.SongTitle.Text="Playing...";
               app.SongTitle.FontColor=[0,1,0];
               if(class(files)=="char")
                [y,Fs]=audioread(files);
                else
                 [y,Fs]=audioread(string(files(sN))); 
                 player=audioplayer(y,fs);
                 app.SongTitle.Text=name;
                app.SongTitle.FontWeight="bold";
                playblocking(player,cRR);
                AssignSongsinSequence(app,event,2);
                end
            elseif(statusforward==1)
                statusforward=0;
               app.instructionLabel.Text="Going for next song...";
               app.instructionLabel.FontColor=[0,1,0];
               pause(1);
               app.instructionLabel.Text="";
               app.SongTitle.Text="";
               if(class(files)=="char")
                [y,Fs]=audioread(files);
                else
                 [y,Fs]=audioread(string(files(sN))); 
                 player=audioplayer(y,fs);
                 app.SongTitle.Text=name;
                app.SongTitle.FontWeight="bold";
                playblocking(player);
                AssignSongsinSequence(app,event,2);
               end
            elseif(statusback==1)
                statusback=0;
               app.instructionLabel.Text="Going for previous song...";
               app.instructionLabel.FontColor=[0,1,0];
               pause(1);
               app.instructionLabel.Text="";
               app.SongTitle.Text="";
               if(class(files)=="char")
                [y,Fs]=audioread(files);
                else
                 [y,Fs]=audioread(string(files(sN))); 
                 player=audioplayer(y,fs);
                 app.SongTitle.Text=name;
                app.SongTitle.FontWeight="bold";
                playblocking(player);
                AssignSongsinSequence(app,event,2);
               end
            elseif(shuffle==1)
                disp("Statusforward");
            elseif(repeat==1)
                disp("Statusforward");
            else
                player=audioplayer(y,fs);
                app.SongTitle.Text=name;
                app.SongTitle.FontWeight="bold";
                playblocking(player);
                AssignSongsinSequence(app,event,2);
            end
            end
        end

        % Image clicked function: pause
        function pauseImageClicked(app, event)
            app.instructionLabel.Text="Pausing!!!";
            app.instructionLabel.FontColor=[1,0,0];
            global player;global statusPause;statusPause=1;
            pause(player);global cRR;
            cRR=get(player,"CurrentSample");
            waitfor(app.PlayButton);
            
        end

        % Image clicked function: ReverseButton
        function ReverseButtonImageClicked(app, event)
             app.instructionLabel.Text="Going to previous song!!!";
            app.instructionLabel.FontColor=[1,0,0];
            global statusback;global player;
            statusback=1;
            pause(player);
            disp("Paused");
            global sN;
            if(sN==1)
                app.instructionLabel.Text="No previous song!";
                app.ReverseButton.Enable="off";
            else
                sN=sN-1;
                stop(player);
                PlayButtonImageClicked(app,event);
            end
        end

        % Image clicked function: ForwardButton
        function ForwardButtonImageClicked(app, event)
           app.instructionLabel.Text="Going to previous song!!!";
           app.instructionLabel.FontColor=[1,0,0];
            global statusforward;global files;
            statusforward=1;global player;global sN;
            disp("paused");
            pause(player);
            if(sN==length(files))
                app.instructionLabel.Text="All songs done!";
                app.ForwardButton.Enable="off";  
            else
                sN=sN+1;
                stop(player);
                PlayButtonImageClicked(app,event);
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseSong = uiimage(app.UIFigure);
            app.BrowseSong.ImageClickedFcn = createCallbackFcn(app, @BrowseSongImageClicked, true);
            app.BrowseSong.ImageSource = 'music_player.png';
            app.PlayButton = uiimage(app.UIFigure);
            app.PlayButton.ImageClickedFcn = createCallbackFcn(app, @PlayButtonImageClicked, true);
            app.PlayButton.ImageSource = 'Play1.png';
            app.ForwardButton = uiimage(app.UIFigure);
            app.ForwardButton.ImageClickedFcn = createCallbackFcn(app, @ForwardButtonImageClicked, true);
            app.ForwardButton.ImageSource = 'forward.png';
            app.ReverseButton = uiimage(app.UIFigure);
            app.ReverseButton.ImageClickedFcn = createCallbackFcn(app, @ReverseButtonImageClicked, true);
            app.ReverseButton.ImageSource = 'backward.png';
            app.SongTitle = uilabel(app.UIFigure);
            app.SongTitle.Text = '';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '';
            app.pause = uiimage(app.UIFigure);
            app.pause.ImageClickedFcn = createCallbackFcn(app, @pauseImageClicked, true);
            app.pause.ImageSource = 'pause.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = musicPlayer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end