classdef second_update < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BrowseSong        matlab.ui.control.Image
        PlayButton        matlab.ui.control.Image
        ForwardButton     matlab.ui.control.Image
        ReverseButton     matlab.ui.control.Image
        SongProgress      matlab.ui.control.Slider
        SongTitle         matlab.ui.control.Label
        LoopSongs         matlab.ui.control.Image
        ShuffleSongs      matlab.ui.control.Image
        instructionLabel  matlab.ui.control.Label
        pause             matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function checkandAssign(app,event)
        global songnames;global player;
        global countsongvariable;
        if(countsongvariable<length(songnames))
            countsongvariable=countsongvariable+1;
            PlayButtonImageClicked(app, event);
            %countsongvariable=countsongvariable+1;
        else
     
            app.instructionLabel.Text="All songs are done playing...";
            app.instructionLabel.FontColor=[0,1,0];
        end
        
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: BrowseSong
        function BrowseSongImageClicked(app, event)
            global songnames;    
        global ShuffleCheck;
        global countsongvariable;
        countsongvariable=1;
        global loopcontrol;
        loopcontrol=0;
        ShuffleCheck=0;
        global allsongplays;
        global pausing;pausing=0;
        global currentSong;
        
        songnames=uigetfile('*.mp3','Select the songs.','MultiSelect','on');
            if(length(songnames)>=2)
                app.instructionLabel.Text='Songs Loaded';
                app.instructionLabel.FontColor=[0,1,0];
                pause(3);
                app.instructionLabel.Text='';
                currentSong=1;
            end
            global songListNumber;
            songListNumber=length(songnames);
            allsongplays=zeros(length(songnames));
        end

        % Image clicked function: PlayButton
        function PlayButtonImageClicked(app, event)
        global player;global pausing;global songnames;
        global countsongvariable;global loopcontrol;
        selectedsong=string(songnames(countsongvariable));
        if(pausing==1)
            resume(player);
            checkandAssign(app,event);
        else
        [y,fs]=audioread(string(songnames(countsongvariable)));
        player=audioplayer(y,fs);
        totalsamples=get(player,'TotalSamples');
        disp(selectedsong);
        playblocking(player);
        if(loopcontrol==0)
            disp("trying");
        checkandAssign(app,event);
        end
        end
        end

        % Image clicked function: pause
        function pauseImageClicked(app, event)
            global player;
            is_pressed=1;
            global pausing;
            disp("Pausing Happens");
            global loopcontrol;
            loopcontrol=1;
            pausing=1;
            pause(player);
        end

        % Image clicked function: ForwardButton
        function ForwardButtonImageClicked(app, event)
            PlayButtonImageClicked(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseSong = uiimage(app.UIFigure);
            app.BrowseSong.ImageClickedFcn = createCallbackFcn(app, @BrowseSongImageClicked, true);
            app.BrowseSong.ImageSource = 'music_player.png';
            app.PlayButton = uiimage(app.UIFigure);
            app.PlayButton.ImageClickedFcn = createCallbackFcn(app, @PlayButtonImageClicked, true);
            app.PlayButton.ImageSource = 'Play1.png';
            app.ForwardButton = uiimage(app.UIFigure);
            app.ForwardButton.ImageClickedFcn = createCallbackFcn(app, @ForwardButtonImageClicked, true);
            app.ForwardButton.ImageSource = 'forward.png';
            app.ReverseButton = uiimage(app.UIFigure);
            app.ReverseButton.ImageSource = 'backward.png';
            app.SongProgress = uislider(app.UIFigure);
            app.SongProgress.Limits = [1 100];
            app.SongProgress.MajorTicks = [];
            app.SongProgress.MinorTicks = [];
            app.SongProgress.Enable = 'off';
            app.SongProgress.Value = 1;
            app.SongTitle = uilabel(app.UIFigure);
            app.SongTitle.Text = 'Demo';
            app.LoopSongs = uiimage(app.UIFigure);
            app.LoopSongs.ImageSource = 'repeat.png';
            app.ShuffleSongs = uiimage(app.UIFigure);
            app.ShuffleSongs.ImageSource = 'shuffle.png';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '';
            app.pause = uiimage(app.UIFigure);
            app.pause.ImageClickedFcn = createCallbackFcn(app, @pauseImageClicked, true);
            app.pause.ImageSource = 'pause.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = second_update

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end