classdef sixth_update < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BrowseSong        matlab.ui.control.Image
        PlayButton        matlab.ui.control.Image
        ForwardButton     matlab.ui.control.Image
        ReverseButton     matlab.ui.control.Image
        SongProgress      matlab.ui.control.Slider
        SongTitle         matlab.ui.control.Label
        LoopSongs         matlab.ui.control.Image
        ShuffleSongs      matlab.ui.control.Image
        instructionLabel  matlab.ui.control.Label
        pause             matlab.ui.control.Image
    end

    
    properties (Access = private)
        PlayButtonHit % Description
    end
    
    methods (Access = private)
        
        function checkandAssign(app,event)
        global songnames;global player;
        disp("Type of songnames: "+string(class(songnames)));
        global countsongvariable;
        if(class(songnames)~="char")
        if(countsongvariable<length(songnames))
            countsongvariable=countsongvariable+1;
            PlayButtonImageClicked(app, event);
            %countsongvariable=countsongvariable+1;
        else
     
            app.instructionLabel.Text="All songs are done playing...";
            app.instructionLabel.FontColor=[0,1,0];
            
        end
        clear all;
        clc;
        disp("Cleared");
        end 
        end
        
        function playSong(app,event,cSr)
        global curr1;global total1;global player;%global cSr;
        play(player,cSr);
        checkandAssign(app,event);            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: BrowseSong
        function BrowseSongImageClicked(app, event)
            global songnames;    
        global ShuffleCheck;
        global cSr;
        cSr=1;
        global countsongvariable;
        countsongvariable=1;
        global loopcontrol;
        loopcontrol=0;
        ShuffleCheck=0;
        global allsongplays;
        global pausing;pausing=0;
        global currentSong;
        app.PlayButtonHit=0;
        songnames=uigetfile('*.mp3','Select the songs.','MultiSelect','on');
            if(length(songnames)>=2)
                app.instructionLabel.Text='Songs Loaded';
                app.instructionLabel.FontColor=[0,1,0];
                pause(3);
                app.instructionLabel.Text='';
                currentSong=1;
            end
            global songListNumber;
            songListNumber=length(songnames);
            allsongplays=zeros(length(songnames));
        end

        % Image clicked function: PlayButton
        function PlayButtonImageClicked(app, event)
        global player;global pausing;global songnames;
        global countsongvariable;global loopcontrol;global cSr;
        app.PlayButtonHit=app.PlayButtonHit+1;
        global pausing;
        if(pausing==1)
            playSong(app,event,cSr);
            pausing=0;
        else
        if(class(songnames)~="char")
        selectedsong=string(songnames(countsongvariable));
        else
            selectedsong=songnames;

        end
        [y,fs]=audioread(selectedsong);
        disp(selectedsong);
        player=audioplayer(y,fs);
        %playblocking(player);
        disp(player);
        global curr1;
        N=length(y)/fs;
        for(i=1:N)
        playSong(app,event,1);
        pause(1);
        end
        checkandAssign(app,event);
        end
        disp("We are done");
        end

        % Image clicked function: pause
        function pauseImageClicked(app, event)
            global player;
            global pausing;
            app.PlayButtonHit=0;
            global cSr;
            disp("Pausing Happens");
            global loopcontrol;
            loopcontrol=1;
            pausing=1;
            cSr=get(player,"CurrentSample");
            pause(player);
            waitfor(app.PlayButton);
        end

        % Image clicked function: ForwardButton
        function ForwardButtonImageClicked(app, event)
            PlayButtonImageClicked(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseSong = uiimage(app.UIFigure);
            app.BrowseSong.ImageClickedFcn = createCallbackFcn(app, @BrowseSongImageClicked, true);
            app.BrowseSong.ImageSource = 'music_player.png';
            app.PlayButton = uiimage(app.UIFigure);
            app.PlayButton.ImageClickedFcn = createCallbackFcn(app, @PlayButtonImageClicked, true);
            app.PlayButton.ImageSource = 'Play1.png';
            app.ForwardButton = uiimage(app.UIFigure);
            app.ForwardButton.ImageClickedFcn = createCallbackFcn(app, @ForwardButtonImageClicked, true);
            app.ForwardButton.ImageSource = 'forward.png';
            app.ReverseButton = uiimage(app.UIFigure);
            app.ReverseButton.ImageSource = 'backward.png';
            app.SongProgress = uislider(app.UIFigure);
            app.SongProgress.Limits = [1 100];
            app.SongProgress.MajorTicks = [];
            app.SongProgress.MinorTicks = [];
            app.SongProgress.Value = 1;
            app.SongTitle = uilabel(app.UIFigure);
            app.SongTitle.Text = 'Demo';
            app.LoopSongs = uiimage(app.UIFigure);
            app.LoopSongs.ImageSource = 'repeat.png';
            app.ShuffleSongs = uiimage(app.UIFigure);
            app.ShuffleSongs.ImageSource = 'shuffle.png';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '';
            app.pause = uiimage(app.UIFigure);
            app.pause.ImageClickedFcn = createCallbackFcn(app, @pauseImageClicked, true);
            app.pause.ImageSource = 'pause.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sixth_update

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end